classdef GUIFeb18 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WeekFeb18UIFigure            matlab.ui.Figure
        BrowseButton                 matlab.ui.control.Button
        SelectfigFileEditFieldLabel  matlab.ui.control.Label
        SelectfigFileEditField       matlab.ui.control.EditField
        SelectLocationtoSaveObjectFileLabel  matlab.ui.control.Label
        SelectOutputLocation         matlab.ui.control.EditField
        OpenFileExplorerOutput       matlab.ui.control.Button
        ConvertButton                matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        VRMathematicalConceptVisualizationLabel  matlab.ui.control.Label
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

           %Replacing axes with the image at top of application
           % Remove title, axis labels, and tick labels
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            I = imshow('TitleImage.png', 'Parent', app.UIAxes, ...
    'XData', [1 app.UIAxes.Position(3)], ...
    'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
        end

        % Button pushed function: BrowseButton
        function OpenFileExplorerButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.*';'*.fig'} , 'search for .fig file path' );
            
            %Prevent GUI glitch of being sent to background after getfile by toggling visibility
            app.WeekFeb18UIFigure.Visible = 'off';
            app.WeekFeb18UIFigure.Visible = 'on';
            
            
            global fullName;
            fullName= [filepath filename];
            [~,~,ext] = fileparts(fullName); % gets extension of selected file path
            expectedExt = '.fig';
            compare = strcmp(ext, expectedExt); % compares extension to .fig 
                                                % if compare == 0, then selected file path is not .fig file
            if compare == 0
                msgbox('Wrong File Type. Please select a .fig file', 'File Type Error', 'error') % display error prompting to select .fig file
                return
            end
            app.SelectfigFileEditField.Value = fullName; % if .fig file, then display file path
        end

        % Button pushed function: OpenFileExplorerOutput
        function OpenFileExplorerOutputButtonPushed(app, event)
           global fullfilename
         
           if(exist('outputdir','var')==0)
               [filename, filefolder] = uiputfile('*.obj', 'Write obj-file');
               
                %Prevent GUI glitch of being sent to background after uiputfile by toggling visibility
                 app.WeekFeb18UIFigure.Visible = 'off';
                 app.WeekFeb18UIFigure.Visible = 'on';
               
               outputdir = [filefolder filename];
            end
           app.SelectOutputLocation.Value = outputdir;
           fullfilename = outputdir; % fullfilename is global variable also used by convert function
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            global fullName;
            GUIconvert(fullName);
            
            %close out of app
            close(app.WeekFeb18UIFigure);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WeekFeb18UIFigure = uifigure;
            app.WeekFeb18UIFigure.Name = 'VR Mathematical Concept Visualization';
            app.BrowseButton = uibutton(app.WeekFeb18UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileExplorerButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SelectfigFileEditFieldLabel = uilabel(app.WeekFeb18UIFigure);
            app.SelectfigFileEditFieldLabel.Text = 'Select .fig File';
            app.SelectfigFileEditField = uieditfield(app.WeekFeb18UIFigure, 'text');
            app.SelectLocationtoSaveObjectFileLabel = uilabel(app.WeekFeb18UIFigure);
            app.SelectLocationtoSaveObjectFileLabel.Text = 'Select Location to Save Object File';
            app.SelectOutputLocation = uieditfield(app.WeekFeb18UIFigure, 'text');
            app.OpenFileExplorerOutput = uibutton(app.WeekFeb18UIFigure, 'push');
            app.OpenFileExplorerOutput.ButtonPushedFcn = createCallbackFcn(app, @OpenFileExplorerOutputButtonPushed, true);
            app.OpenFileExplorerOutput.Text = 'Browse';
            app.ConvertButton = uibutton(app.WeekFeb18UIFigure, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.UIAxes = uiaxes(app.WeekFeb18UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.VRMathematicalConceptVisualizationLabel = uilabel(app.WeekFeb18UIFigure);
            app.VRMathematicalConceptVisualizationLabel.Text = 'VR Mathematical Concept Visualization';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUIFeb18

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WeekFeb18UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WeekFeb18UIFigure)
        end
    end
end