classdef WeekFeb4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WeekFeb4UIFigure            matlab.ui.Figure
        ClicktoSelectfigFileButton  matlab.ui.control.Button
        figFilePathEditFieldLabel   matlab.ui.control.Label
        figFilePathEditField        matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: ClicktoSelectfigFileButton
        function ClicktoSelectfigFileButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.*';'*.fig'} , 'search for .fig file path' );
            fullName = [filepath filename];
            [~,~,ext] = fileparts(fullName); % gets extension of selected file path
            expectedExt = '.fig';
            compare = strcmp(ext, expectedExt); % compares extension to .fig 
                                                % if compare == 0, then selected file path is not .fig file
            if compare == 0
                msgbox('Wrong File Type. Please select a .fig file', 'File Type Error', 'error') % display error prompting to select .fig file
                return
            end
            app.figFilePathEditField.Value = fullName; % if .fig file, then display file path
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WeekFeb4UIFigure = uifigure;
            app.WeekFeb4UIFigure.Name = 'Week Feb 4';
            app.ClicktoSelectfigFileButton = uibutton(app.WeekFeb4UIFigure, 'push');
            app.ClicktoSelectfigFileButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoSelectfigFileButtonPushed, true);
            app.ClicktoSelectfigFileButton.Text = 'Click to Select .fig File';
            app.figFilePathEditFieldLabel = uilabel(app.WeekFeb4UIFigure);
            app.figFilePathEditFieldLabel.Text = '.fig File Path';
            app.figFilePathEditField = uieditfield(app.WeekFeb4UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = WeekFeb4

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WeekFeb4UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WeekFeb4UIFigure)
        end
    end
end