classdef GUIFeb11 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WeekFeb11UIFigure            matlab.ui.Figure
        BrowseButton                 matlab.ui.control.Button
        SelectfigFileEditFieldLabel  matlab.ui.control.Label
        SelectfigFileEditField       matlab.ui.control.EditField
        SelectLocationtoSaveObjectFileLabel  matlab.ui.control.Label
        SelectOutputLocation         matlab.ui.control.EditField
        OpenFileExplorerOutput       matlab.ui.control.Button
        ConvertButton                matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: BrowseButton
        function OpenFileExplorerButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.*';'*.fig'} , 'search for .fig file path' );
            global fullName;
            fullName= [filepath filename];
            [~,~,ext] = fileparts(fullName); % gets extension of selected file path
            expectedExt = '.fig';
            compare = strcmp(ext, expectedExt); % compares extension to .fig 
                                                % if compare == 0, then selected file path is not .fig file
            if compare == 0
                msgbox('Wrong File Type. Please select a .fig file', 'File Type Error', 'error') % display error prompting to select .fig file
                return
            end
            app.SelectfigFileEditField.Value = fullName; % if .fig file, then display file path
        end

        % Button pushed function: OpenFileExplorerOutput
        function OpenFileExplorerOutputButtonPushed(app, event)
           global fullfilename
         
           if(exist('outputdir','var')==0)
               [filename, filefolder] = uiputfile('*.obj', 'Write obj-file');
               outputdir = [filefolder filename];
            end
           app.SelectOutputLocation.Value = outputdir;
           fullfilename = outputdir; % fullfilename is global variable also used by convert function
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            global fullName;
            convert(fullName);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WeekFeb11UIFigure = uifigure;
            app.WeekFeb11UIFigure.Name = 'Week Feb 11';
            app.BrowseButton = uibutton(app.WeekFeb11UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileExplorerButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SelectfigFileEditFieldLabel = uilabel(app.WeekFeb11UIFigure);
            app.SelectfigFileEditFieldLabel.Text = 'Select .fig File';
            app.SelectfigFileEditField = uieditfield(app.WeekFeb11UIFigure, 'text');
            app.SelectLocationtoSaveObjectFileLabel = uilabel(app.WeekFeb11UIFigure);
            app.SelectLocationtoSaveObjectFileLabel.Text = 'Select Location to Save Object File';
            app.SelectOutputLocation = uieditfield(app.WeekFeb11UIFigure, 'text');
            app.OpenFileExplorerOutput = uibutton(app.WeekFeb11UIFigure, 'push');
            app.OpenFileExplorerOutput.ButtonPushedFcn = createCallbackFcn(app, @OpenFileExplorerOutputButtonPushed, true);
            app.OpenFileExplorerOutput.Text = 'Browse';
            app.ConvertButton = uibutton(app.WeekFeb11UIFigure, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUIFeb11

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WeekFeb11UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WeekFeb11UIFigure)
        end
    end
end